classdef GUI_Interpolation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        kSpinner              matlab.ui.control.Spinner
        kSpinnerLabel         matlab.ui.control.Label
        AceptarButton         matlab.ui.control.Button
        CargueunaImagenLabel  matlab.ui.control.Label
        CargarImagenButton    matlab.ui.control.Button
        TypeDropDown          matlab.ui.control.DropDown
        TypeDropDownLabel     matlab.ui.control.Label
        InterpolationLabel    matlab.ui.control.Label
        UIAxes_3              matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
    end

    properties (Access = private)
        imagePath = '' % Description
    end
    methods (Access = private)
        
        function func(app)
            I = imread(app.imagePath);
            if strcmp(app.TypeDropDown.Value,'Nearest neighbor')
                new = Nearest_neighbur(I, app.kSpinner.Value)
                imshow(uint8(I),'Parent',app.UIAxes_3);
                imshow(uint8(new),'Parent',app.UIAxes_2);
            elseif strcmp(app.TypeDropDown.Value,'Bilinear')
                new = Bilinear(I, app.kSpinner.Value)
                imshow(uint8(I),'Parent',app.UIAxes_3);
                imshow(uint8(new),'Parent',app.UIAxes_2);
            elseif strcmp(app.TypeDropDown.Value,'Bicubic')
                new = Bicubic(I, app.kSpinner.Value)
                imshow(uint8(I),'Parent',app.UIAxes_3);
                imshow(uint8(new),'Parent',app.UIAxes_2);
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarImagenButton
        function CargarImagenButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.*';'*.jpg';'*.png';'*.jpeg';'*.bmp'},'Busque un archivo de imagen');
            app.imagePath = strcat(filepath, filename);   
            app.AceptarButton.Enable = true; 
            if app.AceptarButton.Enable == true
                app.func();
            end
        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            
        end

        % Button pushed function: AceptarButton
        function AceptarButtonPushed(app, event)
            app.func()
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.AceptarButton.Enable = false; 
            app.UIAxes_3.Visible = false;
            app.UIAxes_2.Visible = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'After Interpolation')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Before Interpolation')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.InterpolationLabel = uilabel(app.UIFigure);
            app.InterpolationLabel.Text = 'Interpolation';
            app.TypeDropDownLabel = uilabel(app.UIFigure);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.UIFigure);
            app.TypeDropDown.Items = {'Nearest Neighbor', 'Bilinear', 'Bicubic'};
            app.TypeDropDown.Value = 'Nearest Neighbor';
            app.CargarImagenButton = uibutton(app.UIFigure, 'push');
            app.CargarImagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargarImagenButtonPushed, true);
            app.CargarImagenButton.Text = 'Cargar Imagen';
            app.CargueunaImagenLabel = uilabel(app.UIFigure);
            app.CargueunaImagenLabel.Text = 'Cargue una Imagen';
            app.AceptarButton = uibutton(app.UIFigure, 'push');
            app.AceptarButton.ButtonPushedFcn = createCallbackFcn(app, @AceptarButtonPushed, true);
            app.AceptarButton.Text = 'Aceptar';
            app.kSpinnerLabel = uilabel(app.UIFigure);
            app.kSpinnerLabel.Text = 'k';
            app.kSpinner = uispinner(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Interpolation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end